classdef vehicle_model < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        RFRVehicleModelUIFigure         matlab.ui.Figure
        MainMenuPanel                   matlab.ui.container.Panel
        SidebarPanel                    matlab.ui.container.Panel
        SimulatePanel                   matlab.ui.container.Panel
        SimulateButton                  matlab.ui.control.Button
        ChangeCarPropertiesButton       matlab.ui.control.Button
        TrackPanel                      matlab.ui.container.Panel
        TrackChangeButton               matlab.ui.control.Button
        TrackLabel                      matlab.ui.control.Label
        TrackLabel_2                    matlab.ui.control.Label
        VehicleLabel                    matlab.ui.control.Label
        TrackPanel_2                    matlab.ui.container.Panel
        TrackChangeButton_2             matlab.ui.control.Button
        TrackLabel_3                    matlab.ui.control.Label
        AnalysisPanel                   matlab.ui.container.Panel
        TitlePanel                      matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        RyersonFormulaRacingLabel       matlab.ui.control.Label
        VehicleModelLabel               matlab.ui.control.Label
        SimulationPanel                 matlab.ui.container.Panel
        RightSidePanel                  matlab.ui.container.Panel
        ManageSimulationPanel           matlab.ui.container.Panel
        StopButton                      matlab.ui.control.Button
        PauseButton                     matlab.ui.control.Button
        Panel_2                         matlab.ui.container.Panel
        GearLabel                       matlab.ui.control.Label
        GearTextLabel                   matlab.ui.control.Label
        Panel_4                         matlab.ui.container.Panel
        BrakeGaugeLabel                 matlab.ui.control.Label
        BrakeGauge                      matlab.ui.control.LinearGauge
        ThrottleGauge_2Label            matlab.ui.control.Label
        ThrottleGauge                   matlab.ui.control.LinearGauge
        Panel_3                         matlab.ui.container.Panel
        SpeedGaugeLabel                 matlab.ui.control.Label
        SpeedGauge                      matlab.ui.control.Gauge
        RPMGaugeLabel                   matlab.ui.control.Label
        RPMGauge                        matlab.ui.control.Gauge
        x1000Label                      matlab.ui.control.Label
        kmhLabel                        matlab.ui.control.Label
        TimeLabel                       matlab.ui.control.Label
        CarPropertiesPanel              matlab.ui.container.Panel
        PropertiesPanel                 matlab.ui.container.Panel
        SuspensionPanel                 matlab.ui.container.Panel
        ChangesuspensiongeometryButton  matlab.ui.control.Button
        SpringRatesPanel                matlab.ui.container.Panel
        FrontLabel                      matlab.ui.control.Label
        FrontEditField                  matlab.ui.control.NumericEditField
        RearLabel                       matlab.ui.control.Label
        RearEditField                   matlab.ui.control.NumericEditField
        DropDown                        matlab.ui.control.DropDown
        DropDown_2                      matlab.ui.control.DropDown
        DamperRatesPanel                matlab.ui.container.Panel
        FrontLabel_2                    matlab.ui.control.Label
        FrontEditField_2                matlab.ui.control.NumericEditField
        RearLabel_2                     matlab.ui.control.Label
        RearEditField_2                 matlab.ui.control.NumericEditField
        DropDown_3                      matlab.ui.control.DropDown
        DropDown_4                      matlab.ui.control.DropDown
        SteeringPanel                   matlab.ui.container.Panel
        ChangesteeringgeometryButton    matlab.ui.control.Button
        ToeAnglesPanel                  matlab.ui.container.Panel
        FrontEditField_3Label           matlab.ui.control.Label
        FrontEditField_3                matlab.ui.control.NumericEditField
        RearEditField_3Label            matlab.ui.control.Label
        RearEditField_3                 matlab.ui.control.NumericEditField
        DropDown_5                      matlab.ui.control.DropDown
        DropDown_9                      matlab.ui.control.DropDown
        CasterAnglesPanel               matlab.ui.container.Panel
        FrontEditField_4Label           matlab.ui.control.Label
        FrontEditField_4                matlab.ui.control.NumericEditField
        RearEditField_4Label            matlab.ui.control.Label
        RearEditField_4                 matlab.ui.control.NumericEditField
        DropDown_10                     matlab.ui.control.DropDown
        DropDown_11                     matlab.ui.control.DropDown
        GeneralPanel                    matlab.ui.container.Panel
        CarmassLabel                    matlab.ui.control.Label
        CarmassEditField                matlab.ui.control.EditField
        DropDown_12                     matlab.ui.control.DropDown
        TiresLabel                      matlab.ui.control.Label
        TiresDropDown                   matlab.ui.control.DropDown
        CGPositionPanel                 matlab.ui.container.Panel
        HeightLabel                     matlab.ui.control.Label
        HeightEditField                 matlab.ui.control.NumericEditField
        DropDown_13                     matlab.ui.control.DropDown
        LengthLabel                     matlab.ui.control.Label
        LengthEditField                 matlab.ui.control.EditField
        DropDown_14                     matlab.ui.control.DropDown
        WheelbaseLabel                  matlab.ui.control.Label
        WheelbaseEditField              matlab.ui.control.NumericEditField
        DropDown_15                     matlab.ui.control.DropDown
        YawinertiaLabel                 matlab.ui.control.Label
        YawinertiaEditField             matlab.ui.control.NumericEditField
        DropDown_16                     matlab.ui.control.DropDown
        TrackWidthPanel                 matlab.ui.container.Panel
        DropDown_17                     matlab.ui.control.DropDown
        FrontEditField_5Label           matlab.ui.control.Label
        FrontEditField_5                matlab.ui.control.NumericEditField
        RearEditField_5Label            matlab.ui.control.Label
        RearEditField_5                 matlab.ui.control.NumericEditField
        DropDown_18                     matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        State       SimulationState     % Simulation state (running, paused, stopped)
        Model       double              % Model reference
        DrivetrainModel double          % Drivetrain model reference
        SpeedListenerHandle
        RPMListenerHandle
        ThrottleListenerHandle
        BrakeListenerHandle
        GearListenerHandle
        OldModelProperties struct = struct( ...
            'StopTime', 0, ...
            'StartFcn', '', ...
            'StopFcn', '' ...
            )
    end
    
    methods (Access = private)
        
    end
    
    methods (Access = public)
        
        function changeState(app, newState)
            oldState = app.State;
            app.State = newState;
            
            switch newState
                
                case SimulationState.Running % Run the sim
                    
                    app.PauseButton.Text = 'Pause';
                    
                    if oldState == SimulationState.Stopped
                        set_param(app.Model,'SimulationCommand','start'); % Start the model
                    else
                        set_param(app.Model,'SimulationCommand','continue'); % Resume the model
                    end
                    
                case SimulationState.Paused % Pause the sim
                    
                    app.PauseButton.Text = 'Resume';
                    
                    set_param(app.Model,'SimulationCommand','pause');
                    
                case SimulationState.Stopped % Stop the sim
                    
                    % Disable the buttons
                    app.PauseButton.Enable = false;
                    app.StopButton.Enable = false;
                    
                    % Stop the simulation
                    set_param(app.Model,'SimulationCommand','stop');
                    
                    % Reset to the old properties
                    set_param(app.Model, 'StopTime', app.OldModelProperties.StopTime);
                    set_param(app.Model, 'StartFcn', app.OldModelProperties.StartFcn);
                    set_param(app.Model, 'StopFcn', app.OldModelProperties.StopFcn);
                    
                    % Reset the gauges
                    app.SpeedGauge.Value = 0;
                    app.RPMGauge.Value = 0;
                    app.ThrottleGauge.Value = 0;
                    app.BrakeGauge.Value = 0;
            end
        end
        
        function registerListeners(app)
            app.SpeedListenerHandle = add_exec_event_listener( ...
                'Vehicle_Model/Car velocities/Integrator1', ...
                'PostOutputs', ...
                @(block, event) updateSpeed(app, block));
            app.RPMListenerHandle = add_exec_event_listener( ...
                'Drivetrain_2Track/Gain1', ...
                'PostOutputs', ...
                @(block, event) updateRPM(app, block));
            app.ThrottleListenerHandle = add_exec_event_listener( ...
                'Vehicle_Model/Throttle Input', ...
                'PostOutputs', ...
                @(block, event) updateThrottle(app, block));
            app.BrakeListenerHandle = add_exec_event_listener( ...
                'Vehicle_Model/Brake pedal', ...
                'PostOutputs', ...
                @(block, event) updateBrake(app, block));
            app.GearListenerHandle = add_exec_event_listener( ...
                'Vehicle_Model/Gear Input', ...
                'PostOutputs', ...
                @(block, event) updateGear(app, block));
        end
        
        function stopSimulation(app)
            app.changeState(SimulationState.Stopped);
        end
        
        function updateSpeed(app, block)
            value = block.OutputPort(1).Data;
            app.SpeedGauge.Value = value(1) * 3.6;
        end
        
        function updateRPM(app, block)
            value = block.OutputPort(1).Data;
            app.RPMGauge.Value = value(1) / 1000;
        end
        
        function updateThrottle(app, block)
            value = block.OutputPort(1).Data;
            app.ThrottleGauge.Value = value(1) * 100;
        end
        
        function updateBrake(app, block)
            value = block.OutputPort(1).Data;
            app.BrakeGauge.Value = value(1) * 100;
        end
        
        function updateGear(app, block)
            value = block.OutputPort(1).Data;
            app.GearLabel.Text = num2str(value);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MainMenuPanel.Position = app.RFRVehicleModelUIFigure.Position;
            app.SimulationPanel.Visible = false;
            app.SimulationPanel.Position = app.RFRVehicleModelUIFigure.Position;
            
            global app_global;
            app_global = app;
        end

        % Button pushed function: PauseButton
        function pauseButtonPushed(app, event)
            switch app.State
                case SimulationState.Running
                    changeState(app, SimulationState.Paused);
                case SimulationState.Paused
                    changeState(app, SimulationState.Running);
            end
        end

        % Button pushed function: SimulateButton
        function simulateButtonPushed(app, event)
            app.MainMenuPanel.Visible = false;
            app.SimulationPanel.Visible = true;
            
            app.Model = load_system('Vehicle_Model.slx'); % Load the model
            app.DrivetrainModel = load_system('Drivetrain_2Track.slx'); % Load the drivetrain
            
            % Save the old properties
            app.OldModelProperties.StopTime = get_param(app.Model,'StopTime');
            app.OldModelProperties.StartFcn = get_param(app.Model,'StartFcn');
            app.OldModelProperties.StartFcn = get_param(app.Model,'StopFcn');
            
            set_param(app.Model,'StopTime','inf');
            set_param(app.Model,'StartFcn','global app_global; app_global.registerListeners');
            set_param(app.Model,'StopFcn','global app_global; app_global.stopSimulation');
            
            get_param(app.Model,'SimulationStatus')
            
            changeState(app,SimulationState.Running);
            
        end

        % Size changed function: RFRVehicleModelUIFigure
        function RFRVehicleModelUIFigureSizeChanged(app, event)
            position = app.RFRVehicleModelUIFigure.Position;
            app.MainMenuPanel.Position = position;
            app.MainMenuPanel.Position(2) = position(2) - 41;
            app.MainMenuPanel.Position(4) = position(4) + 41;
            
            app.SimulationPanel.Position = position;
            app.SimulationPanel.Position(2) = position(2) - 41;
            app.SimulationPanel.Position(4) = position(4) + 41;
            
            app.SidebarPanel.Position(1) = app.MainMenuPanel.Position(3) - app.SidebarPanel.Position(3);
            app.SidebarPanel.Position(2) = app.MainMenuPanel.Position(2) - 41;
            app.SidebarPanel.Position(4) = app.MainMenuPanel.Position(4) + 1;
            
            app.TitlePanel.Position(2) = app.MainMenuPanel.Position(4) - app.TitlePanel.Position(4) - 40;
            app.TitlePanel.Position(3) = app.MainMenuPanel.Position(3) - app.SidebarPanel.Position(3);
            
            app.RightSidePanel.Position(1) = app.SimulationPanel.Position(3) - app.RightSidePanel.Position(3);
            app.RightSidePanel.Position(2) = app.SimulationPanel.Position(2);
            app.RightSidePanel.Position(4) = app.SimulationPanel.Position(4);
            
            app.TimeLabel.Position(1) = app.RightSidePanel.Position(1) - 100;
            app.TimeLabel.Position(2) = app.SimulationPanel.Position(4) - 80;
        end

        % Close request function: RFRVehicleModelUIFigure
        function RFRVehicleModelUIFigureCloseRequest(app, event)
            delete(app)
            %close_system('Lateral_Dynamics_2Track', false);
            %close_system('Drivetrain_2Track',false);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.RFRVehicleModelUIFigure = uifigure('Visible', 'off');
            app.RFRVehicleModelUIFigure.Name = 'RFR Vehicle Model';
            app.RFRVehicleModelUIFigure.CloseRequestFcn = createCallbackFcn(app, @RFRVehicleModelUIFigureCloseRequest, true);
            app.RFRVehicleModelUIFigure.SizeChangedFcn = createCallbackFcn(app, @RFRVehicleModelUIFigureSizeChanged, true);
            app.MainMenuPanel = uipanel(app.RFRVehicleModelUIFigure);
            app.SidebarPanel = uipanel(app.MainMenuPanel);
            app.SimulatePanel = uipanel(app.SidebarPanel);
            app.SimulatePanel.Title = 'Simulate';
            app.SimulateButton = uibutton(app.SimulatePanel, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @simulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.ChangeCarPropertiesButton = uibutton(app.SimulatePanel, 'push');
            app.ChangeCarPropertiesButton.Text = 'Change Car Properties';
            app.TrackPanel = uipanel(app.SimulatePanel);
            app.TrackChangeButton = uibutton(app.TrackPanel, 'push');
            app.TrackChangeButton.Text = 'Change';
            app.TrackLabel = uilabel(app.TrackPanel);
            app.TrackLabel.Enable = 'off';
            app.TrackLabel.Text = 'None selected';
            app.TrackLabel_2 = uilabel(app.SimulatePanel);
            app.TrackLabel_2.Text = 'Track';
            app.VehicleLabel = uilabel(app.SimulatePanel);
            app.VehicleLabel.Text = 'Vehicle';
            app.TrackPanel_2 = uipanel(app.SimulatePanel);
            app.TrackChangeButton_2 = uibutton(app.TrackPanel_2, 'push');
            app.TrackChangeButton_2.Text = 'Change';
            app.TrackLabel_3 = uilabel(app.TrackPanel_2);
            app.TrackLabel_3.Enable = 'off';
            app.TrackLabel_3.Text = 'None selected';
            app.AnalysisPanel = uipanel(app.SidebarPanel);
            app.AnalysisPanel.Title = 'Analysis';
            app.TitlePanel = uipanel(app.MainMenuPanel);
            app.Image = uiimage(app.TitlePanel);
            app.RyersonFormulaRacingLabel = uilabel(app.TitlePanel);
            app.RyersonFormulaRacingLabel.Text = 'Ryerson Formula Racing';
            app.VehicleModelLabel = uilabel(app.TitlePanel);
            app.VehicleModelLabel.Text = 'Vehicle Model';
            app.SimulationPanel = uipanel(app.RFRVehicleModelUIFigure);
            app.RightSidePanel = uipanel(app.SimulationPanel);
            app.ManageSimulationPanel = uipanel(app.RightSidePanel);
            app.StopButton = uibutton(app.ManageSimulationPanel, 'push');
            app.StopButton.Text = 'Stop';
            app.PauseButton = uibutton(app.ManageSimulationPanel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @pauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.Panel_2 = uipanel(app.RightSidePanel);
            app.GearLabel = uilabel(app.Panel_2);
            app.GearLabel.Text = '1';
            app.GearTextLabel = uilabel(app.Panel_2);
            app.GearTextLabel.Text = 'Gear';
            app.Panel_4 = uipanel(app.Panel_2);
            app.BrakeGaugeLabel = uilabel(app.Panel_4);
            app.BrakeGaugeLabel.Text = 'Brake';
            app.BrakeGauge = uigauge(app.Panel_4, 'linear');
            app.ThrottleGauge_2Label = uilabel(app.Panel_4);
            app.ThrottleGauge_2Label.Text = 'Throttle';
            app.ThrottleGauge = uigauge(app.Panel_4, 'linear');
            app.Panel_3 = uipanel(app.RightSidePanel);
            app.SpeedGaugeLabel = uilabel(app.Panel_3);
            app.SpeedGaugeLabel.Text = 'Speed';
            app.SpeedGauge = uigauge(app.Panel_3, 'circular');
            app.RPMGaugeLabel = uilabel(app.Panel_3);
            app.RPMGaugeLabel.Text = 'RPM';
            app.RPMGauge = uigauge(app.Panel_3, 'circular');
            app.RPMGauge.Limits = [4 11];
            app.RPMGauge.Value = 4;
            app.x1000Label = uilabel(app.Panel_3);
            app.x1000Label.Text = 'x1000';
            app.kmhLabel = uilabel(app.Panel_3);
            app.kmhLabel.Text = 'km/h';
            app.TimeLabel = uilabel(app.SimulationPanel);
            app.TimeLabel.Text = '0:00:00';
            app.CarPropertiesPanel = uipanel(app.RFRVehicleModelUIFigure);
            app.PropertiesPanel = uipanel(app.CarPropertiesPanel);
            app.SuspensionPanel = uipanel(app.PropertiesPanel);
            app.SuspensionPanel.Title = 'Suspension';
            app.ChangesuspensiongeometryButton = uibutton(app.SuspensionPanel, 'push');
            app.ChangesuspensiongeometryButton.Text = 'Change suspension geometry';
            app.SpringRatesPanel = uipanel(app.SuspensionPanel);
            app.SpringRatesPanel.Title = 'Spring Rates';
            app.FrontLabel = uilabel(app.SpringRatesPanel);
            app.FrontLabel.Text = 'Front:';
            app.FrontEditField = uieditfield(app.SpringRatesPanel, 'numeric');
            app.RearLabel = uilabel(app.SpringRatesPanel);
            app.RearLabel.Text = 'Rear:';
            app.RearEditField = uieditfield(app.SpringRatesPanel, 'numeric');
            app.DropDown = uidropdown(app.SpringRatesPanel);
            app.DropDown.Items = {'N/m', 'N/mm', '', 'lbf/in'};
            app.DropDown.Value = 'N/m';
            app.DropDown_2 = uidropdown(app.SpringRatesPanel);
            app.DropDown_2.Items = {'N/m', 'N/mm', '', 'lbf/in'};
            app.DropDown_2.Value = 'N/m';
            app.DamperRatesPanel = uipanel(app.SuspensionPanel);
            app.DamperRatesPanel.Title = 'Damper Rates';
            app.FrontLabel_2 = uilabel(app.DamperRatesPanel);
            app.FrontLabel_2.Text = 'Front:';
            app.FrontEditField_2 = uieditfield(app.DamperRatesPanel, 'numeric');
            app.RearLabel_2 = uilabel(app.DamperRatesPanel);
            app.RearLabel_2.Text = 'Rear:';
            app.RearEditField_2 = uieditfield(app.DamperRatesPanel, 'numeric');
            app.DropDown_3 = uidropdown(app.DamperRatesPanel);
            app.DropDown_3.Items = {'N*s/m', 'N*s/mm', 'lbf*s/in'};
            app.DropDown_3.Value = 'N*s/m';
            app.DropDown_4 = uidropdown(app.DamperRatesPanel);
            app.DropDown_4.Items = {'N*s/m', 'N*s/mm', 'lbf*s/in'};
            app.DropDown_4.Value = 'N*s/m';
            app.SteeringPanel = uipanel(app.PropertiesPanel);
            app.SteeringPanel.Title = 'Steering';
            app.ChangesteeringgeometryButton = uibutton(app.SteeringPanel, 'push');
            app.ChangesteeringgeometryButton.Text = 'Change steering geometry';
            app.ToeAnglesPanel = uipanel(app.SteeringPanel);
            app.ToeAnglesPanel.Title = 'Toe Angles';
            app.FrontEditField_3Label = uilabel(app.ToeAnglesPanel);
            app.FrontEditField_3Label.Text = 'Front';
            app.FrontEditField_3 = uieditfield(app.ToeAnglesPanel, 'numeric');
            app.RearEditField_3Label = uilabel(app.ToeAnglesPanel);
            app.RearEditField_3Label.Text = 'Rear';
            app.RearEditField_3 = uieditfield(app.ToeAnglesPanel, 'numeric');
            app.DropDown_5 = uidropdown(app.ToeAnglesPanel);
            app.DropDown_5.Items = {'deg', 'rad'};
            app.DropDown_5.Value = 'deg';
            app.DropDown_9 = uidropdown(app.ToeAnglesPanel);
            app.DropDown_9.Items = {'deg', 'rad'};
            app.DropDown_9.Value = 'deg';
            app.CasterAnglesPanel = uipanel(app.SteeringPanel);
            app.CasterAnglesPanel.Title = 'Caster Angles';
            app.FrontEditField_4Label = uilabel(app.CasterAnglesPanel);
            app.FrontEditField_4Label.Text = 'Front';
            app.FrontEditField_4 = uieditfield(app.CasterAnglesPanel, 'numeric');
            app.RearEditField_4Label = uilabel(app.CasterAnglesPanel);
            app.RearEditField_4Label.Text = 'Rear';
            app.RearEditField_4 = uieditfield(app.CasterAnglesPanel, 'numeric');
            app.DropDown_10 = uidropdown(app.CasterAnglesPanel);
            app.DropDown_10.Items = {'deg', 'rad'};
            app.DropDown_10.Value = 'deg';
            app.DropDown_11 = uidropdown(app.CasterAnglesPanel);
            app.DropDown_11.Items = {'deg', 'rad'};
            app.DropDown_11.Value = 'deg';
            app.GeneralPanel = uipanel(app.PropertiesPanel);
            app.GeneralPanel.Title = 'General';
            app.CarmassLabel = uilabel(app.GeneralPanel);
            app.CarmassLabel.Tooltip = {'Without driver or aero package'};
            app.CarmassLabel.Text = 'Car mass:';
            app.CarmassEditField = uieditfield(app.GeneralPanel, 'text');
            app.CarmassEditField.Value = '0';
            app.DropDown_12 = uidropdown(app.GeneralPanel);
            app.DropDown_12.Items = {'kg', 'lbm'};
            app.DropDown_12.Value = 'kg';
            app.TiresLabel = uilabel(app.GeneralPanel);
            app.TiresLabel.Text = 'Tires:';
            app.TiresDropDown = uidropdown(app.GeneralPanel);
            app.CGPositionPanel = uipanel(app.GeneralPanel);
            app.CGPositionPanel.Title = 'CG Position';
            app.HeightLabel = uilabel(app.CGPositionPanel);
            app.HeightLabel.Text = 'Height:';
            app.HeightEditField = uieditfield(app.CGPositionPanel, 'numeric');
            app.HeightEditField.Tooltip = {'Vertical distance from ground to CG, when car is loaded and stationary'};
            app.DropDown_13 = uidropdown(app.CGPositionPanel);
            app.DropDown_13.Items = {'mm', 'in'};
            app.DropDown_13.Value = 'mm';
            app.LengthLabel = uilabel(app.CGPositionPanel);
            app.LengthLabel.Text = 'Length:';
            app.LengthEditField = uieditfield(app.CGPositionPanel, 'text');
            app.LengthEditField.Tooltip = {'Distance in x-direction from rear axle to CG'};
            app.LengthEditField.Value = '0';
            app.DropDown_14 = uidropdown(app.CGPositionPanel);
            app.DropDown_14.Items = {'mm', 'in'};
            app.DropDown_14.Value = 'mm';
            app.WheelbaseLabel = uilabel(app.GeneralPanel);
            app.WheelbaseLabel.Text = 'Wheelbase:';
            app.WheelbaseEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.DropDown_15 = uidropdown(app.GeneralPanel);
            app.DropDown_15.Items = {'m', 'mm', 'ft', 'in'};
            app.DropDown_15.Value = 'm';
            app.YawinertiaLabel = uilabel(app.GeneralPanel);
            app.YawinertiaLabel.Text = 'Yaw inertia:';
            app.YawinertiaEditField = uieditfield(app.GeneralPanel, 'numeric');
            app.DropDown_16 = uidropdown(app.GeneralPanel);
            app.DropDown_16.Items = {'kg*m^2', 'lbm*inft^2'};
            app.DropDown_16.Value = 'kg*m^2';
            app.TrackWidthPanel = uipanel(app.GeneralPanel);
            app.TrackWidthPanel.Title = 'Track Width';
            app.DropDown_17 = uidropdown(app.TrackWidthPanel);
            app.DropDown_17.Items = {'m', 'mm', 'ft', 'in'};
            app.DropDown_17.Value = 'm';
            app.FrontEditField_5Label = uilabel(app.TrackWidthPanel);
            app.FrontEditField_5Label.Text = 'Front:';
            app.FrontEditField_5 = uieditfield(app.TrackWidthPanel, 'numeric');
            app.RearEditField_5Label = uilabel(app.TrackWidthPanel);
            app.RearEditField_5Label.Text = 'Rear:';
            app.RearEditField_5 = uieditfield(app.TrackWidthPanel, 'numeric');
            app.DropDown_18 = uidropdown(app.TrackWidthPanel);
            app.DropDown_18.Items = {'m', 'mm', 'ft', 'in'};
            app.DropDown_18.Value = 'm';
            % Show the figure after all components are created
            app.RFRVehicleModelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = vehicle_model

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.RFRVehicleModelUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.RFRVehicleModelUIFigure)
        end
    end
end